classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ensureZeroConfigWarning    matlab.ui.control.Label
        StartRecordingButton       matlab.ui.control.Button
        BottomText                 matlab.ui.control.Label
        AnglesPanel                matlab.ui.container.Panel
        JointAnglesdegLabel        matlab.ui.control.Label
        AnglesEditField            matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
        TorquePanel                matlab.ui.container.Panel
        TorqueSwitchLabel          matlab.ui.control.Label
        TorqueSwitch               matlab.ui.control.Switch
        TCPPanel                   matlab.ui.container.Panel
        TCPPosLabel                matlab.ui.control.Label
        TCPPosEditField            matlab.ui.control.EditField
        PortDropDown               matlab.ui.control.DropDown
        PortDropDownLabel          matlab.ui.control.Label
        ConnectDisconnectButton    matlab.ui.control.Button
        StatusPanel                matlab.ui.container.Panel
        StatusLabel                matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        ControlPanel               matlab.ui.container.Panel
        StopButton                 matlab.ui.control.Button
        KGainSlider                matlab.ui.control.Slider
        KGainSliderLabel           matlab.ui.control.Label
        DemoButton                 matlab.ui.control.Button
        GoButton                   matlab.ui.control.Button
        ZeroButton                 matlab.ui.control.Button
        InitButton                 matlab.ui.control.Button
        ProgramArgumentsPanel      matlab.ui.container.Panel
        ProgramArgumentsLabel      matlab.ui.control.Label
        ProgramArgumentsEditField  matlab.ui.control.EditField
        ProgramSelectPanel         matlab.ui.container.Panel
        ProgramSelectLabel         matlab.ui.control.Label
        ProgramSelectDropdown      matlab.ui.control.DropDown
    end


    properties
        launcher = [];
        is_disconnecting = false;
        isRecording = false; % Flag to keep track of recording state
        recordedPositions = []; % To store recorded positions
        recordedJointAngles = []; % To store recorded joint angles
        recordingTimer; % Timer object for recording at intervals
        recordedTimes = []; % To store time stamps of each recording step

    end


    methods

        function updateGUI(app)

            % If the robot is connected
            if ~isempty(app.launcher.realRobot)  && ~app.is_disconnecting
                app.StatusLabel.Text = 'Connected';
                app.ConnectDisconnectButton.Text = 'Disconnect';
                app.StatusLamp.Color = [0,1,0]; % green
                app.TorquePanel.Visible = "on";
                app.TCPPanel.Visible = "on";
                app.AnglesPanel.Visible = "on";
                app.StartRecordingButton.Visible = "on";
                app.ensureZeroConfigWarning.Visible = "off";

                % Update TCP Position
                pos_col_vec = app.launcher.virtualRobot.forwardKinematics();
                rounded_pos = round(pos_col_vec);
                pos_strs = arrayfun(@(x) sprintf('%4d', x), rounded_pos, 'UniformOutput', false);
                app.TCPPosEditField.Value = strjoin(pos_strs, ' ');


                % Update Joint Angles
                q = rad2deg(app.launcher.virtualRobot.getJointAngles());
                rounded_q = round(q);
                rounded_q(abs(rounded_q) < 0.1) = 0;
                angle_strs = arrayfun(@(x) sprintf('%4d', x), rounded_q, 'UniformOutput', false);
                app.AnglesEditField.Value = strjoin(angle_strs, ' ');


                % Update Singularity Warning
                if app.launcher.virtualRobot.checkSingularity()
                    app.StatusLabel.Text = 'Singularity';
                    app.StatusLamp.Color = [1,1,0];
                else
                    app.StatusLabel.Text = 'Ready';
                    app.StatusLamp.Color = [0,1,0];
                end

                % If the torque is on
                if strcmp(app.TorqueSwitch.Value, 'On')
                    app.ControlPanel.Visible = "on";
                else
                    app.ControlPanel.Visible = "off";
                end

            else
                % Robot is about to disconnect
                if app.is_disconnecting
                    app.StatusLabel.Text = 'Disconnecting...';
                    app.StatusLamp.Color = [0;0;1];
                    % Robot is disconnected
                else
                    app.ensureZeroConfigWarning.Visible = "on";
                    app.ConnectDisconnectButton.Text = 'Connect';
                    app.StatusLabel.Text = 'Disconnected';
                    app.StatusLamp.Color = [0.15,0.15,0.15];
                end

                app.TorquePanel.Visible = "off";
                app.ControlPanel.Visible = "off";
                app.TCPPanel.Visible = "off";
                app.AnglesPanel.Visible = "off";
                app.StartRecordingButton.Visible = "off";


                % Check available COM ports and update PortDropDown
                availablePorts = serialportlist;
                if isempty(availablePorts)
                    app.PortDropDown.Items = {'NA'};
                    app.ConnectDisconnectButton.Text = 'Scan Ports';
                else
                    app.PortDropDown.Items = availablePorts;
                    app.ConnectDisconnectButton.Text = 'Connect';
                end

            end

        end
    end

    methods (Access = private)

        function recordData(app)
            if app.isRecording
                % Get current data
                currentPosition = app.launcher.virtualRobot.forwardKinematics();
                currentJointAngles = app.launcher.virtualRobot.getJointAngles();

                % Store data
                app.recordedPositions = [app.recordedPositions, currentPosition];
                app.recordedJointAngles = [app.recordedJointAngles, currentJointAngles];

                % Calculate and store the elapsed time since the start of recording
                % If this is the first entry, the time is 0. Otherwise, it's the increment.
                if isempty(app.recordedTimes)
                    elapsedTime = 0;
                else
                    elapsedTime = app.recordedTimes(end) + app.recordingTimer.Period;
                end
                app.recordedTimes = [app.recordedTimes; elapsedTime];
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            screenSize = get(0, 'ScreenSize'); % Get the screen size
            margin = 25;

            % App is always in the top right corner of the screen
            app.UIFigure.Position = [2*screenSize(3)/3, 1, screenSize(3)/3, screenSize(4)-margin];

            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

            % Add Path to Launcher
            currentDir = fileparts(mfilename('fullpath'));
            launcherPath = fullfile(currentDir, 'src', 'Programs');
            addpath(launcherPath);

            % Initialize the Launcher
            app.launcher = Launcher(app);
            app.ProgramSelectDropdown.Items = app.launcher.getPrograms;

            app.updateGUI;

            app.ProgramSelectDropdownValueChanged;

        end

        % Button pushed function: ConnectDisconnectButton
        function ConnectDisconnectButtonPushed(app, event)


            if strcmp(app.ConnectDisconnectButton.Text, 'Connect')
                % Connect Button
                selectedPort = app.PortDropDown.Value;
                if ~strcmp(selectedPort, 'NA')
                    success = app.launcher.connect(selectedPort); % Call with the selected port
                    if ~success
                        app.StatusLamp.Color = [1.00, 0.00, 0.00];
                        app.StatusLabel.Text = sprintf('Failed on Port %s', selectedPort); % Corrected line
                    end
                end
            elseif strcmp(app.ConnectDisconnectButton.Text, 'Disconnect')
                % Disconnect Button
                app.is_disconnecting = true;
                if ~isempty(app.launcher.realRobot) && isvalid(app.launcher.realRobot)
                    app.launcher.realRobot.setRobotTorque(1);
                    app.KGainSlider.Value = 2.5;
                    app.ZeroButtonPushed;
                    app.launcher.disconnect;
                end
                app.is_disconnecting = false;
                app.updateGUI;
            end

            % Check available COM ports and update PortDropDown
            availablePorts = serialportlist;
            if isempty(availablePorts)
                app.PortDropDown.Items = {'NA'};
                app.ConnectDisconnectButton.Text = 'Scan Ports';
            else
                app.PortDropDown.Items = availablePorts;
                app.ConnectDisconnectButton.Text = 'Connect';
            end

            % Update Torque Switch Value
            if ~isempty(app.launcher.realRobot) && isvalid(app.launcher.realRobot)
                if app.launcher.realRobot.getRobotTorque()
                    app.TorqueSwitch.Value = 'On';
                else
                    app.TorqueSwitch.Value = 'Off';
                end
            end
        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            delete(app.launcher.activeProgram)
            if strcmp(app.TorqueSwitch.Value, 'On')
                app.launcher.realRobot.setRobotTorque(1);
            else
                app.launcher.realRobot.setRobotTorque(0);
            end
        end

        % Value changed function: ProgramSelectDropdown
        function ProgramSelectDropdownValueChanged(app, event)

            selectedProgram = app.ProgramSelectDropdown.Value;

            % Fetch arguments information
            argsInfo = feval([selectedProgram '.getArgumentsInfo']);

            % Update UI elements
            app.ProgramArgumentsEditField.Value = '';
            app.ProgramArgumentsLabel.Text = argsInfo.name;
            app.ProgramArgumentsEditField.Placeholder = argsInfo.placeholder;
            app.ProgramArgumentsEditField.Visible = "on";


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.ConnectDisconnectButton.Text, 'Disconnect')
                app.ConnectDisconnectButtonPushed;
            end

            delete(app.launcher)
            delete(app.UIFigure);
            close all

        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            Kp = app.KGainSlider.Value;
            app.launcher.launchProgram('SetJoints',[10;-10;-20;100;], 'Kp', Kp, 'precision_deg', 0.4);
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            Kp = app.KGainSlider.Value;
            app.launcher.launchProgram('SetJoints',[0;0;0;0], 'Kp', Kp, 'breakTimerStopValue', 2);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            Kp = app.KGainSlider.Value;
            programName = app.ProgramSelectDropdown.Value;
            args = app.ProgramArgumentsEditField.Value;
            [is_valid, error_msg] = app.launcher.checkProgramArgs(programName, args);

            % Check if the arguments are valid
            if ~is_valid
                % Display an alert with the error message
                uialert(app.UIFigure, error_msg, 'Invalid Arguments', 'Icon', 'error');
            else
                % For Programs that use the Nullspace Controller
                if ~strcmp(programName, 'SetJoints')
                    % Half Kp
                    Kp = Kp/2;
                    % Check Singularity
                    if app.launcher.virtualRobot.checkSingularity()
                        % Give UI Alert
                        uialert(app.UIFigure, 'Press Init button to change configuration.','Near Singularity');
                        return;
                    end
                end
                % Convert the arguments string to a numeric column vector
                args_numeric = str2num(args);
                args_numeric = args_numeric(:); % Ensure it's a column vector
                app.launcher.launchProgram(programName, args_numeric, 'Kp', Kp);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            delete(app.launcher.activeProgram)
        end

        % Button pushed function: DemoButton
        function DemoButtonPushed(app, event)

            numJoints = 4;
            joint_limits = rad2deg(app.launcher.virtualRobot.JOINT_ANGLE_LIMITS);
            joint_limits(1,:) = joint_limits(1,:)*0.7;
            joint_limits(2,:) = joint_limits(2,:)*0.7;
            joint_limits(3,:) = joint_limits(3,:)*0.35;
            joint_limits(4,:) = joint_limits(4,:)*0.7;

            % Set Elbow to 90 deg
            app.launcher.launchProgram('SetJoints', [0;0;0;90], 'precision_deg',3, 'Kp', 3);

            % Turn Around z-Axis
            app.launcher.launchProgram('SetJoints', [0;0;360;90], 'precision_deg',5, 'Kp', 2);
            app.launcher.launchProgram('SetJoints', [0;0;-360;90], 'precision_deg',5, 'Kp', 2);

            % Touch behind
            app.launcher.launchProgram('SetJoints', [-45;0;-90;95], 'precision_deg',5, 'Kp', 1.5);

            % Touch forward
            app.launcher.launchProgram('SetJoints', [45;0;90;95], 'precision_deg',5, 'Kp', 1.5);

            % Touch right
            app.launcher.launchProgram('SetJoints', [0;-45;180;95], 'precision_deg',5, 'Kp', 1.5);

            % Touch left
            app.launcher.launchProgram('SetJoints', [0;45;0;95], 'precision_deg',5, 'Kp', 1.5);

            % Go zero
            app.launcher.launchProgram('SetJoints', zeros(numJoints, 1), 'precision_deg',3, 'Kp', 3);

            % Move to forward limit
            app.launcher.launchProgram('SetJoints', joint_limits(:,1), 'precision_deg',5, 'Kp', 2);

            % Move to backward limit
            app.launcher.launchProgram('SetJoints', joint_limits(:,2), 'precision_deg',5, 'Kp', 2);

            % Move back to zero
            app.launcher.launchProgram('SetJoints',[0;0;0;0], 'breakTimerStopValue', 2);

        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            % Button pushed function: StartRecordingButton
            if ~app.isRecording
                % Start recording
                app.isRecording = true;
                app.StartRecordingButton.Text = 'Stop Recording';
                app.StartRecordingButton.BackgroundColor = [1, 0, 0]; % Bright red
                app.recordedPositions = [];
                app.recordedJointAngles = [];
                app.recordedTimes = []; % Initialize time storage

                % Start timer for periodic recording
                app.recordingTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1/40, ...
                    'TimerFcn', @(src, event)recordData(app));
                start(app.recordingTimer);

            else
                % Stop recording
                app.isRecording = false;
                app.StartRecordingButton.Text = 'Start Recording'; % Change the button text back
                app.StartRecordingButton.BackgroundColor = [0.50, 0.50, 0.50]; % Light grey

                stop(app.recordingTimer);
                delete(app.recordingTimer);

                % Extract the properties to local variables
                recordedPositions = app.recordedPositions;
                recordedJointAngles = app.recordedJointAngles;
                recordedTimes = app.recordedTimes'; % Include the times in the saved data

                % Correctly define the save path
                currentDir = fileparts(mfilename('fullpath'));
                recordingsPath = fullfile(currentDir, 'Recordings'); % Adjusted to directly include 'Recordings'

                % Check if the directory exists, if not, create it
                if ~exist(recordingsPath, 'dir')
                    mkdir(recordingsPath);
                end

                timestamp = datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss');
                filename = sprintf('Recording_%s.mat', timestamp);

                % Proceed to save the file
                save(fullfile(recordingsPath, filename), 'recordedPositions', 'recordedJointAngles', 'recordedTimes');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Visible = 'off';
            app.ProgramSelectPanel = uipanel(app.ControlPanel);
            app.ProgramSelectDropdown = uidropdown(app.ProgramSelectPanel);
            app.ProgramSelectDropdown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5'};
            app.ProgramSelectDropdown.ValueChangedFcn = createCallbackFcn(app, @ProgramSelectDropdownValueChanged, true);
            app.ProgramSelectLabel = uilabel(app.ProgramSelectPanel);
            app.ProgramSelectLabel.Text = 'Select Program';
            app.ProgramArgumentsPanel = uipanel(app.ControlPanel);
            app.ProgramArgumentsEditField = uieditfield(app.ProgramArgumentsPanel, 'text');
            app.ProgramArgumentsEditField.Placeholder = 'y, x, z, x';
            app.ProgramArgumentsLabel = uilabel(app.ProgramArgumentsPanel);
            app.ProgramArgumentsLabel.Text = 'Joint Angles [deg]';
            app.InitButton = uibutton(app.ControlPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.ZeroButton = uibutton(app.ControlPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.GoButton = uibutton(app.ControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.DemoButton = uibutton(app.ControlPanel, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @DemoButtonPushed, true);
            app.DemoButton.Text = 'Demo';
            app.KGainSliderLabel = uilabel(app.ControlPanel);
            app.KGainSliderLabel.Text = 'K - Gain';
            app.KGainSlider = uislider(app.ControlPanel);
            app.KGainSlider.Limits = [0 5];
            app.KGainSlider.Value = 2.5;
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusLamp = uilamp(app.StatusPanel);
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Disconnected';
            app.ConnectDisconnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDisconnectButtonPushed, true);
            app.ConnectDisconnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'NA', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.PortDropDown.Value = 'NA';
            app.TCPPanel = uipanel(app.UIFigure);
            app.TCPPosEditField = uieditfield(app.TCPPanel, 'text');
            app.TCPPosEditField.Editable = 'off';
            app.TCPPosEditField.Value = '-100 -100 -100';
            app.TCPPosLabel = uilabel(app.TCPPanel);
            app.TCPPosLabel.Text = 'TCP Position [mm]';
            app.TorquePanel = uipanel(app.UIFigure);
            app.TorqueSwitch = uiswitch(app.TorquePanel, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.TorqueSwitchLabel = uilabel(app.TorquePanel);
            app.TorqueSwitchLabel.Text = 'Torque';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'TUM_logo.png');
            app.AnglesPanel = uipanel(app.UIFigure);
            app.AnglesEditField = uieditfield(app.AnglesPanel, 'text');
            app.AnglesEditField.Editable = 'off';
            app.AnglesEditField.Value = '-100 -100 -100 -100';
            app.JointAnglesdegLabel = uilabel(app.AnglesPanel);
            app.JointAnglesdegLabel.Text = 'Joint Angles [deg]';
            app.BottomText = uilabel(app.UIFigure);
            app.BottomText.Text = {'Thesis:'; 'S. Zeitler, "Towards Dual-Arm Aerial Manipulation: An Exploration in Robotic Arm Design", 2024.'; 'TUM, Chair for Autonomous Aerial Systems, Prof. Markus Ryll'; ''; ''};
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = {'Start'; 'Recording'};
            app.ensureZeroConfigWarning = uilabel(app.UIFigure);
            app.ensureZeroConfigWarning.Text = 'Ensure robotic arm is in zero configuration q = 0 before connecting.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end